classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.StateButton
        SumEditFieldLabel         matlab.ui.control.Label
        SumEditField              matlab.ui.control.NumericEditField
        TexttosendEditFieldLabel  matlab.ui.control.Label
        TexttosendEditField       matlab.ui.control.EditField
        SendButton                matlab.ui.control.Button
        SerialPortDropDownLabel   matlab.ui.control.Label
        SerialPortDropDown        matlab.ui.control.DropDown
    end

    
    % Variable initialization
    properties (Access = public)
        i_sum = 0; % Variable that stores the sum value
        sumTrigger = 0; % Trigger variable for stoping the sum
        ArduinoMess = ''; % String variable that stores the message to Arduino IDO
        serialVar; % Variable for serial port communication
        sendTrigger = 0; % % Trigger variable for sending the message
    end
    
    methods (Access = private)
        
        function [summer, trigger] = StartButtonCounter(app)
            summer = 0;
            while get(app.StartButton,'Value') == 1
                summer = summer + 1;
                drawnow;
                if app.sendTrigger == 1 
                    MessageSender(app);
                end
            end
            if get(app.StartButton,'Value') == 0
                trigger = 1;
                return
            end
        end
        
        function MessageSender(app)
            fopen(app.serialVar);
            fprintf(app.serialVar,'%s',app.ArduinoMess);
            fclose(app.serialVar);
            app.ArduinoMess = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
            app.serialVar = serial(app.SerialPortDropDown.Value);
            if app.StartButton.Value == 1
                app.sumTrigger = app.StartButton.Value;
                app.StartButton.Text = 'Stop';
                app.SumEditField.Value = 0;
                app.TexttosendEditFieldLabel.Enable = 'on';
                app.TexttosendEditField.Editable = 'on';
                app.TexttosendEditField.Enable = 'on';
                drawnow;
                [app.i_sum, app.sumTrigger] = StartButtonCounter(app);
            end
            if app.sumTrigger == 1
                app.StartButton.Text = 'Start';
                app.TexttosendEditFieldLabel.Enable = 'off';
                app.TexttosendEditField.Editable = 'off';
                app.TexttosendEditField.Enable = 'off';
                app.SumEditField.Value = app.i_sum;

                drawnow;
                app.i_sum = 0;
                app.sumTrigger = 0;
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.ArduinoMess = app.TexttosendEditField.Value;
            app.ArduinoMess = strcat(app.ArduinoMess, '\n');
            app.TexttosendEditField.Value = '';
            drawnow;
            app.sendTrigger = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SumEditFieldLabel = uilabel(app.UIFigure);
            app.SumEditFieldLabel.Text = 'Sum';
            app.SumEditField = uieditfield(app.UIFigure, 'numeric');
            app.SumEditField.Editable = 'off';
            app.TexttosendEditFieldLabel = uilabel(app.UIFigure);
            app.TexttosendEditFieldLabel.Enable = 'off';
            app.TexttosendEditFieldLabel.Text = 'Text to send';
            app.TexttosendEditField = uieditfield(app.UIFigure, 'text');
            app.TexttosendEditField.Editable = 'off';
            app.TexttosendEditField.Enable = 'off';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.SerialPortDropDown.Value = 'COM1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end